classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoiseDensityWHzEditFieldLabel   matlab.ui.control.Label
        No                              matlab.ui.control.NumericEditField
        BandwidthMHzEditFieldLabel      matlab.ui.control.Label
        BW                              matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        UrbanMacroCellsPanel            matlab.ui.container.Panel
        NumberofBSDropDownLabel         matlab.ui.control.Label
        number_of_uma                   matlab.ui.control.DropDown
        SeparationbetweenBSmEditField_2Label  matlab.ui.control.Label
        dist_between_uma                matlab.ui.control.NumericEditField
        FrequencyofUMaHzEditFieldLabel  matlab.ui.control.Label
        freq_uma                        matlab.ui.control.NumericEditField
        TxPowerdBmEditFieldLabel        matlab.ui.control.Label
        tx_power_uma                    matlab.ui.control.NumericEditField
        LOSProbabilityEditFieldLabel    matlab.ui.control.Label
        los_uma                         matlab.ui.control.NumericEditField
        UrbanMicroCellsPanel            matlab.ui.container.Panel
        SeparationbetweenBSmEditFieldLabel  matlab.ui.control.Label
        dist_between_umi                matlab.ui.control.NumericEditField
        FrequencyofUMiHzEditFieldLabel  matlab.ui.control.Label
        freq_umi                        matlab.ui.control.NumericEditField
        TxPowerdBmEditField_2Label      matlab.ui.control.Label
        tx_power_umi                    matlab.ui.control.NumericEditField
        NumberofBSDropDown_2Label       matlab.ui.control.Label
        number_of_umi                   matlab.ui.control.DropDown
        LOSProbabilityEditField_2Label  matlab.ui.control.Label
        los_umi                         matlab.ui.control.NumericEditField
        ReceiversPanel                  matlab.ui.container.Panel
        NumberofRxEditFieldLabel        matlab.ui.control.Label
        number_of_rx                    matlab.ui.control.NumericEditField
        WalkeddistancemetersLabel       matlab.ui.control.Label
        walked_distance                 matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Button pushed function: CalculateButton
        function calculate(app, event)
            params = Parameters(app.freq_uma.Value, app.freq_umi.Value, str2double( app.number_of_uma.Value ), str2double( app.number_of_umi.Value ),...
                app.dist_between_uma.Value, app.dist_between_umi.Value, app.number_of_rx.Value, app.walked_distance.Value, app.tx_power_uma.Value, ...
                app.tx_power_umi.Value, app.BW.Value, app.No.Value, app.los_uma.Value, app.los_umi.Value);
            
            save('parameters.mat', 'params');
                
            delete(app);
        end

        % Close request function: UIFigure
        function closing(app, event)
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Simulation parameters';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closing, true);
            app.NoiseDensityWHzEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseDensityWHzEditFieldLabel.Text = 'Noise Density (W/Hz)';
            app.No = uieditfield(app.UIFigure, 'numeric');
            app.No.Value = 4.04e-21;
            app.BandwidthMHzEditFieldLabel = uilabel(app.UIFigure);
            app.BandwidthMHzEditFieldLabel.Text = 'Bandwidth (MHz)';
            app.BW = uieditfield(app.UIFigure, 'numeric');
            app.BW.Value = 100;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.UrbanMacroCellsPanel = uipanel(app.UIFigure);
            app.UrbanMacroCellsPanel.Title = 'Urban Macro Cells';
            app.NumberofBSDropDownLabel = uilabel(app.UrbanMacroCellsPanel);
            app.NumberofBSDropDownLabel.Text = 'Number of BS';
            app.number_of_uma = uidropdown(app.UrbanMacroCellsPanel);
            app.number_of_uma.Items = {'1', '7', '19', '37'};
            app.number_of_uma.Value = '7';
            app.SeparationbetweenBSmEditField_2Label = uilabel(app.UrbanMacroCellsPanel);
            app.SeparationbetweenBSmEditField_2Label.Text = 'Separation between BS (m)';
            app.dist_between_uma = uieditfield(app.UrbanMacroCellsPanel, 'numeric');
            app.dist_between_uma.Limits = [0 Inf];
            app.dist_between_uma.Value = 5000;
            app.FrequencyofUMaHzEditFieldLabel = uilabel(app.UrbanMacroCellsPanel);
            app.FrequencyofUMaHzEditFieldLabel.Text = 'Frequency of UMa (Hz)';
            app.freq_uma = uieditfield(app.UrbanMacroCellsPanel, 'numeric');
            app.freq_uma.Limits = [500000000 60000000000];
            app.freq_uma.Value = 2000000000;
            app.TxPowerdBmEditFieldLabel = uilabel(app.UrbanMacroCellsPanel);
            app.TxPowerdBmEditFieldLabel.Text = 'Tx Power (dBm)';
            app.tx_power_uma = uieditfield(app.UrbanMacroCellsPanel, 'numeric');
            app.tx_power_uma.Value = 40;
            app.LOSProbabilityEditFieldLabel = uilabel(app.UrbanMacroCellsPanel);
            app.LOSProbabilityEditFieldLabel.Text = 'LOS Probability';
            app.los_uma = uieditfield(app.UrbanMacroCellsPanel, 'numeric');
            app.los_uma.Limits = [0 1];
            app.los_uma.Value = 0.2;
            app.UrbanMicroCellsPanel = uipanel(app.UIFigure);
            app.UrbanMicroCellsPanel.Title = 'Urban Micro Cells';
            app.SeparationbetweenBSmEditFieldLabel = uilabel(app.UrbanMicroCellsPanel);
            app.SeparationbetweenBSmEditFieldLabel.Text = 'Separation between BS (m)';
            app.dist_between_umi = uieditfield(app.UrbanMicroCellsPanel, 'numeric');
            app.dist_between_umi.Limits = [0 Inf];
            app.dist_between_umi.Value = 2200;
            app.FrequencyofUMiHzEditFieldLabel = uilabel(app.UrbanMicroCellsPanel);
            app.FrequencyofUMiHzEditFieldLabel.Text = 'Frequency of UMi (Hz)';
            app.freq_umi = uieditfield(app.UrbanMicroCellsPanel, 'numeric');
            app.freq_umi.Limits = [500000000 60000000000];
            app.freq_umi.Value = 20000000000;
            app.TxPowerdBmEditField_2Label = uilabel(app.UrbanMicroCellsPanel);
            app.TxPowerdBmEditField_2Label.Text = 'Tx Power (dBm)';
            app.tx_power_umi = uieditfield(app.UrbanMicroCellsPanel, 'numeric');
            app.tx_power_umi.Value = 27;
            app.NumberofBSDropDown_2Label = uilabel(app.UrbanMicroCellsPanel);
            app.NumberofBSDropDown_2Label.Text = 'Number of BS';
            app.number_of_umi = uidropdown(app.UrbanMicroCellsPanel);
            app.number_of_umi.Items = {'1', '7', '19', '37'};
            app.number_of_umi.Value = '37';
            app.LOSProbabilityEditField_2Label = uilabel(app.UrbanMicroCellsPanel);
            app.LOSProbabilityEditField_2Label.Text = 'LOS Probability';
            app.los_umi = uieditfield(app.UrbanMicroCellsPanel, 'numeric');
            app.los_umi.Limits = [0 1];
            app.los_umi.Value = 0.2;
            app.ReceiversPanel = uipanel(app.UIFigure);
            app.ReceiversPanel.Title = 'Receivers';
            app.NumberofRxEditFieldLabel = uilabel(app.ReceiversPanel);
            app.NumberofRxEditFieldLabel.Text = 'Number of Rx';
            app.number_of_rx = uieditfield(app.ReceiversPanel, 'numeric');
            app.number_of_rx.Limits = [1 Inf];
            app.number_of_rx.Value = 1;
            app.WalkeddistancemetersLabel = uilabel(app.ReceiversPanel);
            app.WalkeddistancemetersLabel.Text = 'Walked distance (meters)';
            app.walked_distance = uieditfield(app.ReceiversPanel, 'numeric');
            app.walked_distance.Limits = [2 Inf];
            app.walked_distance.Value = 3000;
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end