classdef visualization_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnviromentPanel             matlab.ui.container.Panel
        ShowEnviromentButton        matlab.ui.control.Button
        PlotdataPanel               matlab.ui.container.Panel
        PairingcriteriaButtonGroup  matlab.ui.container.ButtonGroup
        better_sinr                 matlab.ui.control.ToggleButton
        nearest_bs                  matlab.ui.control.ToggleButton
        CellslayerButtonGroup       matlab.ui.container.ButtonGroup
        uma                         matlab.ui.control.ToggleButton
        umi                         matlab.ui.control.ToggleButton
        DataButtonGroup             matlab.ui.container.ButtonGroup
        sinr                        matlab.ui.control.ToggleButton
        capacity                    matlab.ui.control.ToggleButton
        pairing                     matlab.ui.control.ToggleButton
        power                       matlab.ui.control.ToggleButton
        PlotButton                  matlab.ui.control.Button
        holdon                      matlab.ui.control.CheckBox
        ReceiversPanel              matlab.ui.container.Panel
        ReceiverIDEditFieldLabel    matlab.ui.control.Label
        no_rx                       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        layout_uma % The UMa layer of the simulation
        sinr_uma % The UMa SINR for every user at each snapshot
        capacity_uma % The UMa capacity for every user at each snapshot
        pairing_uma % The UMa simulation pairings
        rx_power_uma % The compilation of Rx power in each snapshot
        layout_umi % The UMi layer of the simulation
        sinr_umi % The UMi SINR for every user at each snapshot
        capacity_umi % The UMi capacity for every user at each snapshot
        pairing_umi % The UMi simulation pairings
        rx_power_umi % The compilation of Rx power in each snapshot
    end


    methods (Access = private)

        % Code that executes after component creation
        function creating(app)
            load('output.mat');
            delete('output.mat');
            app.layout_uma = layout_uma;
            app.layout_umi = layout_umi;
            app.sinr_uma = sinr_uma;
            app.sinr_umi = sinr_umi;
            app.capacity_uma = capacity_uma;
            app.capacity_umi = capacity_umi;
            app.pairing_uma = pairing_uma;
            app.pairing_umi = pairing_umi;
            app.rx_power_uma = rx_power_uma;
            app.rx_power_umi = rx_power_umi;
        end

        % Button pushed function: PlotButton
        function visualize_data(app, event)
            rx_id = app.no_rx.Value;
            hold_on = app.holdon.Value;
            
            if (app.uma.Value)
                type = 'UMa';
                pairing = app.pairing_uma;
            else
                type = 'UMi';
                pairing = app.pairing_umi;
            end
            
            if (app.better_sinr.Value)
                criteria = 'SINR';
            else
                criteria = 'Nearest BS';
            end
            
            if (app.sinr.Value)
                data = 'SINR';
                if strcmp(type,'UMa')
                    input = app.sinr_uma;
                else
                    input = app.sinr_umi;
                end
            elseif (app.capacity.Value)
                data = 'Capacity';
                if strcmp(type, 'UMa')
                    input = app.capacity_uma;
                else
                    input = app.capacity_umi;
                end
            elseif (app.power.Value)
                data = 'Power';
                if strcmp(type,'UMa')
                    input = app.rx_power_uma;
                else
                    input = app.rx_power_umi;
                end
            else
                data = 'Pairing';
                input = pairing;
            end
            
            
            show_data(criteria, type, data, rx_id, pairing, hold_on, input);
        end

        % Button pushed function: ShowEnviromentButton
        function visualize_enviroment(app, event)
            show_enviroment([app.layout_umi app.layout_uma]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Visualization Panel';
            app.EnviromentPanel = uipanel(app.UIFigure);
            app.EnviromentPanel.Title = 'Enviroment';
            app.ShowEnviromentButton = uibutton(app.EnviromentPanel, 'push');
            app.ShowEnviromentButton.ButtonPushedFcn = createCallbackFcn(app, @visualize_enviroment, true);
            app.ShowEnviromentButton.Text = 'Show Enviroment';
            app.PlotdataPanel = uipanel(app.UIFigure);
            app.PlotdataPanel.Title = 'Plot data';
            app.PairingcriteriaButtonGroup = uibuttongroup(app.PlotdataPanel);
            app.PairingcriteriaButtonGroup.Title = 'Pairing criteria';
            app.better_sinr = uitogglebutton(app.PairingcriteriaButtonGroup);
            app.better_sinr.Text = 'Better SINR';
            app.better_sinr.Value = true;
            app.nearest_bs = uitogglebutton(app.PairingcriteriaButtonGroup);
            app.nearest_bs.Text = 'Nearest BS';
            app.CellslayerButtonGroup = uibuttongroup(app.PlotdataPanel);
            app.CellslayerButtonGroup.Title = 'Cells layer';
            app.uma = uitogglebutton(app.CellslayerButtonGroup);
            app.uma.Text = 'UMa';
            app.uma.Value = true;
            app.umi = uitogglebutton(app.CellslayerButtonGroup);
            app.umi.Text = 'UMi';
            app.DataButtonGroup = uibuttongroup(app.PlotdataPanel);
            app.DataButtonGroup.Title = 'Data';
            app.sinr = uitogglebutton(app.DataButtonGroup);
            app.sinr.Text = 'SINR';
            app.sinr.Value = true;
            app.capacity = uitogglebutton(app.DataButtonGroup);
            app.capacity.Text = 'Capacity';
            app.pairing = uitogglebutton(app.DataButtonGroup);
            app.pairing.Text = 'Pairing evolution';
            app.power = uitogglebutton(app.DataButtonGroup);
            app.power.Text = 'Power';
            app.PlotButton = uibutton(app.PlotdataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @visualize_data, true);
            app.PlotButton.Text = 'Plot';
            app.holdon = uicheckbox(app.PlotdataPanel);
            app.holdon.Text = '  Plot in the previous figure';
            app.ReceiversPanel = uipanel(app.PlotdataPanel);
            app.ReceiversPanel.Title = 'Receivers';
            app.ReceiverIDEditFieldLabel = uilabel(app.ReceiversPanel);
            app.ReceiverIDEditFieldLabel.Text = 'Receiver ID';
            app.no_rx = uieditfield(app.ReceiversPanel, 'numeric');
            app.no_rx.Limits = [1 Inf];
            app.no_rx.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualization_panel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @creating)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end